classdef Synthesiser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        ImportMenu            matlab.ui.container.Menu
        ExportMenu            matlab.ui.container.Menu
        ClearMenu             matlab.ui.container.Menu
        PlaybackMenu          matlab.ui.container.Menu
        PlayMenu              matlab.ui.container.Menu
        PauseMenu             matlab.ui.container.Menu
        StopMenu              matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        TabGroup2             matlab.ui.container.TabGroup
        EqualiserTab          matlab.ui.container.Tab
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        ResetButton           matlab.ui.control.Button
        EqSlider16k           matlab.ui.control.Slider
        Label_10              matlab.ui.control.Label
        EqSlider8k            matlab.ui.control.Slider
        Label_9               matlab.ui.control.Label
        EqSlider4k            matlab.ui.control.Slider
        Label_8               matlab.ui.control.Label
        EqSlider2k            matlab.ui.control.Slider
        Label_7               matlab.ui.control.Label
        EqSlider1k            matlab.ui.control.Slider
        Label_6               matlab.ui.control.Label
        EqSlider512           matlab.ui.control.Slider
        Label_5               matlab.ui.control.Label
        EqSlider256           matlab.ui.control.Slider
        Label_4               matlab.ui.control.Label
        EqSlider128           matlab.ui.control.Slider
        Label_3               matlab.ui.control.Label
        EqSlider64            matlab.ui.control.Slider
        Label_2               matlab.ui.control.Label
        EqSlider32            matlab.ui.control.Slider
        Label                 matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        WaveformTab           matlab.ui.container.Tab
        WaveformContainer     matlab.ui.container.GridLayout
        WaveformAxes          matlab.ui.control.UIAxes
        MagnitudeTab          matlab.ui.container.Tab
        MagnitudeContainer    matlab.ui.container.GridLayout
        MagnitudeAxes         matlab.ui.control.UIAxes
        SpectrogramTab        matlab.ui.container.Tab
        SpectrogramContainer  matlab.ui.container.GridLayout
        SpectrogramAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sample % The sample loaded from file for the synthesiser.
        sampleFft % The fourier transform of the loaded sample.
        sampleRate % The sample rate of the currently loaded audio sample.
        sampleName % The name of the file being used as a sample.

        audioPlayer % The current audio player object in use.
        
        shapePoint1 % The first point of the shape currently being drawn.
        shapes % Array of subtractive shapes drawn on the spectrogram.
        shapeColor = "blue" % The color of the shapes when drawn on the spectrogram.

        eqBands = [
            [32,0]
            [64,0]
            [128,0]
            [256,0]
            [512,0]
            [1024,0]
            [2048,0]
            [4096,0]
            [8192,0]
            [16384,0]
        ] % Matrix of equaliser settings. Each vector contained specifies a frequency and an attenuation amount.
    end
    
    methods (Access = private)
        % --== Audio file interaction methods ==-- %
        % Method to import an audio sample
        function [y, Fs] = importAudio(app)
            [file, path] = uigetfile(["*.mp3;*.flac;*.m4a;*.mp4;*.oga;*.ogg;*.wav", "Audio Files"]);

            if path ~= 0
                app.clearAudio();
                fullPath = append(path, file);
                [y, Fs] = audioread(fullPath);
                
                app.sample = y(:, 1);
                % Convert to mono by averaging both channels
                % TODO: Not sure if this is the best way to do this
                if size(y, 2) > 1
                    app.sample = (app.sample + y(:, 2)) / 2;
                end

                app.sampleRate = Fs;
                app.sampleName = file;
                app.sampleFft = fft(app.sample);
            end
        end
        
        % Method to export the audio resulting from the synthesiser changes
        function fullPath = exportAudio(app)
            [file, path] = uiputfile(["*.flac;*.oga;*.ogg;*.wav", "Audio files"]);

            if path ~= 0
                fullPath = append(path, file);
                audiowrite(fullPath, app.sample, app.sampleRate);
            end
        end
        
        % Method to clear the current audio sample from storage
        function clearAudio(app)
            app.sample = [];
            app.sampleRate = 0;
            app.sampleName = "None";

            app.audioPlayer = {};
        end
        
        
        % --== Graphing methods ==-- %
        % Method to plot the graphs in the app
        function plotGraphs(app)
            app.clearGraphs();
            if ~isempty(app.sample)
                % Define variables used by all plots
                y = app.sample;
                N = length(y);
                Fs = app.sampleRate;
                
                % Array of timestamps for each sample
                time = (0:N-1)/Fs;

                % Plot Spectrogram
                axes = app.SpectrogramAxes;
                [S, F, T] = spectrogram(y, 100);
                % Display the image with hit test off so we can intercept
                % mouse down events on the axes itself
                imagesc(axes, [0, max(time)], [0, 1], log(abs(S)), "HitTest", "off");
                set(axes, "YDir", "normal")
                xlim(axes, [0, max(time)]);
                ylim(axes, [0, 1]);

                % Plot Waveform
                axes = app.WaveformAxes;
                plot(axes, time, y, "b");
                xlim(axes, [0, max(time)]);
                ylim(axes, [-1, 1]);

                % Plot Magnitude Spectrum
                axes = app.MagnitudeAxes;
                shifted = fftshift(abs(app.sampleFft/N));
                freqs = ((-N/2:N/2-1)/N)*Fs;
                plot(axes, freqs, shifted, "b");
                xlim(axes, [min(freqs), max(freqs)])
            end
        end
        
        % Method to clear graphs of data
        function clearGraphs(app)
            cla(app.WaveformAxes);
            cla(app.SpectrogramAxes);
            cla(app.MagnitudeAxes);
            app.clearShapes();
        end
    

        % --== Audio playback methods ==-- %
        % Play the current audio generated by app.processAudio
        % If app.audioPlayer is defined, will resume playback
        function playAudio(app)
            % Create a new audio player if one is not already assigned.
            if isempty(app.audioPlayer)
                y = app.processAudio();
                Fs = app.sampleRate;
                if ~isempty(y)
                    app.audioPlayer = audioplayer(y, Fs);
                    app.audioPlayer.StopFcn = @(~, ~) app.stopAudio();
                end
            end

            if ~isempty(app.audioPlayer)
                resume(app.audioPlayer);
            end
        end
    
        % Pause the currently playing audio
        function pauseAudio(app)
            if ~isempty(app.audioPlayer)
                pause(app.audioPlayer);
            end
        end
        
        % Stop the currently playing audio
        function stopAudio(app)
            if ~isempty(app.audioPlayer)
                % Check if audio player is still playing so we dont
                % automatically stop after pausing.
                if app.audioPlayer.isplaying()
                    stop(app.audioPlayer);
                    app.audioPlayer = {};
                end
            end
        end
           

        % --== Audio processing methods ==-- %
        % Process the audio according to the applications state
        function results = processAudio(app)
            yFft = app.sampleFft;

            % Apply global equaliser settings
            for i = 1:size(app.eqBands,1)
                % For each band in the equaliser
                nyquist = app.sampleRate/2;
                targetFreq = app.eqBands(i, 1);
                attenuation = app.eqBands(i, 2);

                % Get frequencies of the band below and above this one
                lowFreq = app.eqBands(max(i - 1, 1), 1);
                highFreq = app.eqBands(min(i + 1, size(app.eqBands, 1)), 1);

                % Calculate bounds for this frequency band such that no two bands intersect each other
                lowerBound = (lowFreq + targetFreq) / 2;
                upperBound = (highFreq + targetFreq) / 2;
                
                % Generate and apply each filter
                % Divide by nyquist frequency to normalise bounds
                filter = fourierBandpass(length(yFft), lowerBound/nyquist, upperBound/nyquist);
                % We can't multiply by -1 here as it messes up the complex numbers in the fourier transform
                filtered = (yFft .* filter) * abs(attenuation);
                
                if attenuation < 0
                    yFft = yFft - filtered;
                else
                    yFft = yFft + filtered;
                end
            end
            
            % TODO: Graphical synthesiser stuff
            results = real(ifft(yFft));
        end
        

        % --== Shape interaction methods ==-- %
        % Shapes are used to define what part of the spectrum to subtract
        % from the rest
        function addShape(app, points)
            % Initalise the array if it is empty
            if isempty(app.shapes)
                app.shapes = [];
            end
            
            % Add shape to the shapes array
            app.shapes = [app.shapes; points];
            
            % Show the shape on the graph
            app.drawShape(points);
        end
        
        % Draw the given shape on the axes
        % Points should be an array of points in 2d space.
        %   For example: [1, 1, 1, 2, 2, 2, 2, 1] would be a square.
        function drawShape(app, points)
            axes = app.SpectrogramAxes;
            hold(axes, "on");
            
            pgon = polyshape(points(1:2:end), points(2:2:end));
            plot( ...
                axes, pgon, ...
                "FaceColor", app.shapeColor, ...
                "LineStyle", "none", ...
                "FaceAlpha", 1, ...
                "EdgeAlpha", 1, ...
                "HitTest", "off" ...
            );
        end

        % Delete all currently stored shapes
        function clearShapes(app)
            app.shapes = [];
        end

        
        % --== Global equaliser interaction methods ==-- %
        % Set the global eq bands scalars
        % Takes an index of the band and the scalar to multiply by as
        % arguments
        function setEqBand(app, idx, scalar)
            app.eqBands(idx, 2) = scalar;
            % Clear the audio player whenver we edit eq bands
            app.audioPlayer = {};
        end
        
        % Reset eqSettings to default values
        function resetEqBands(app)
            app.audioPlayer = {};
            
            app.eqBands = [
                [32,0]
                [64,0]
                [128,0]
                [256,0]
                [512,0]
                [1024,0]
                [2048,0]
                [4096,0]
                [8192,0]
                [16384,0]
            ];
            
            % Reset slider values as well
            app.EqSlider32.Value = 0;
            app.EqSlider64.Value = 0;
            app.EqSlider128.Value = 0;
            app.EqSlider256.Value = 0;
            app.EqSlider512.Value = 0;
            app.EqSlider1k.Value = 0;
            app.EqSlider2k.Value = 0;
            app.EqSlider4k.Value = 0;
            app.EqSlider8k.Value = 0;
            app.EqSlider16k.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup spectrogram axes for callback integration
            disableDefaultInteractivity(app.SpectrogramAxes)

            % Setup waveform axes
            disableDefaultInteractivity(app.WaveformAxes);

            % Setup magnitude axes
            disableDefaultInteractivity(app.MagnitudeAxes);
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            app.importAudio();
            app.plotGraphs();
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.exportAudio();
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.clearAudio();
            app.clearGraphs();
        end

        % Menu selected function: PlayMenu
        function PlayMenuSelected(app, event)
            app.playAudio();
        end

        % Menu selected function: PauseMenu
        function PauseMenuSelected(app, event)
            app.pauseAudio();
        end

        % Menu selected function: StopMenu
        function StopMenuSelected(app, event)
            app.stopAudio();
        end

        % Button down function: SpectrogramAxes
        function SpectrogramAxesButtonDown(app, event)
            if event.Button == 1
                % If app.shapePoint1 is not empty, we begin a new shape
                if isempty(app.shapePoint1)
                    app.shapePoint1 = event.IntersectionPoint(1:2);
                else
                    % Generate rectangle edges
                    point1 = app.shapePoint1;
                    point2 = event.IntersectionPoint(1:2);
                    points = [
                        point1(1), point1(2)...
                        point1(1), point2(2)...
                        point2(1), point2(2)...
                        point2(1), point1(2)...
                    ];
                    app.addShape(points);
                    app.shapePoint1 = [];
                end
            end
        end

        % Value changed function: EqSlider32
        function EqSlider32ValueChanged(app, event)
            value = app.EqSlider32.Value;
            app.setEqBand(1, value);
        end

        % Value changed function: EqSlider64
        function EqSlider64ValueChanged(app, event)
            value = app.EqSlider64.Value;
            app.setEqBand(2, value);
        end

        % Value changed function: EqSlider128
        function EqSlider128ValueChanged(app, event)
            value = app.EqSlider128.Value;
            app.setEqBand(3, value);
        end

        % Value changed function: EqSlider256
        function EqSlider256ValueChanged(app, event)
            value = app.EqSlider256.Value;
            app.setEqBand(4, value);
        end

        % Value changed function: EqSlider512
        function EqSlider512ValueChanged(app, event)
            value = app.EqSlider512.Value;
            app.setEqBand(5, value);
        end

        % Value changed function: EqSlider1k
        function EqSlider1kValueChanged(app, event)
            value = app.EqSlider1k.Value;
            app.setEqBand(6, value);
        end

        % Value changed function: EqSlider2k
        function EqSlider2kValueChanged(app, event)
            value = app.EqSlider2k.Value;
            app.setEqBand(7, value);
        end

        % Value changed function: EqSlider4k
        function EqSlider4kValueChanged(app, event)
            value = app.EqSlider4k.Value;
            app.setEqBand(8, value);
        end

        % Value changed function: EqSlider8k
        function EqSlider8kValueChanged(app, event)
            value = app.EqSlider8k.Value;
            app.setEqBand(9, value);
        end

        % Value changed function: EqSlider16k
        function EqSlider16kValueChanged(app, event)
            value = app.EqSlider16k.Value;
            app.setEqBand(10, value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetEqBands();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Separator = 'on';
            app.ImportMenu.Accelerator = 'o';
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Accelerator = 's';
            app.ExportMenu.Text = 'Export';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Separator = 'on';
            app.ClearMenu.Accelerator = 'c';
            app.ClearMenu.Text = 'Clear';
            app.PlaybackMenu = uimenu(app.UIFigure);
            app.PlaybackMenu.Text = 'Playback';
            app.PlayMenu = uimenu(app.PlaybackMenu);
            app.PlayMenu.MenuSelectedFcn = createCallbackFcn(app, @PlayMenuSelected, true);
            app.PlayMenu.Accelerator = 'e';
            app.PlayMenu.Text = 'Play';
            app.PauseMenu = uimenu(app.PlaybackMenu);
            app.PauseMenu.MenuSelectedFcn = createCallbackFcn(app, @PauseMenuSelected, true);
            app.PauseMenu.Accelerator = 'q';
            app.PauseMenu.Text = 'Pause';
            app.StopMenu = uimenu(app.PlaybackMenu);
            app.StopMenu.MenuSelectedFcn = createCallbackFcn(app, @StopMenuSelected, true);
            app.StopMenu.Accelerator = 'r';
            app.StopMenu.Text = 'Stop';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WaveformTab = uitab(app.TabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.WaveformContainer = uigridlayout(app.WaveformTab);
            app.WaveformAxes = uiaxes(app.WaveformContainer);
            title(app.WaveformAxes, 'Waveform')
            xlabel(app.WaveformAxes, 'Time (s)')
            ylabel(app.WaveformAxes, 'Magnitude')
            app.MagnitudeTab = uitab(app.TabGroup);
            app.MagnitudeTab.Title = 'Magnitude';
            app.MagnitudeContainer = uigridlayout(app.MagnitudeTab);
            app.MagnitudeAxes = uiaxes(app.MagnitudeContainer);
            title(app.MagnitudeAxes, 'Magnitude Spectrum')
            xlabel(app.MagnitudeAxes, 'Frequency (Hz)')
            ylabel(app.MagnitudeAxes, 'Magnitude')
            zlabel(app.MagnitudeAxes, 'Z')
            app.SpectrogramTab = uitab(app.TabGroup);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.SpectrogramContainer = uigridlayout(app.SpectrogramTab);
            app.SpectrogramAxes = uiaxes(app.SpectrogramContainer);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'Time (s)')
            ylabel(app.SpectrogramAxes, 'Normalised Frequency (log)')
            app.SpectrogramAxes.ButtonDownFcn = createCallbackFcn(app, @SpectrogramAxesButtonDown, true);
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.EqualiserTab = uitab(app.TabGroup2);
            app.EqualiserTab.Title = 'Equaliser';
            app.GridLayout2 = uigridlayout(app.EqualiserTab);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '32 Hz';
            app.EqSlider32 = uislider(app.GridLayout2);
            app.EqSlider32.Limits = [-1 1];
            app.EqSlider32.MajorTicks = [-1 0 1];
            app.EqSlider32.MajorTickLabels = {''};
            app.EqSlider32.ValueChangedFcn = createCallbackFcn(app, @EqSlider32ValueChanged, true);
            app.EqSlider32.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '64 Hz';
            app.EqSlider64 = uislider(app.GridLayout2);
            app.EqSlider64.Limits = [-1 1];
            app.EqSlider64.MajorTicks = [-1 0 1];
            app.EqSlider64.MajorTickLabels = {''};
            app.EqSlider64.ValueChangedFcn = createCallbackFcn(app, @EqSlider64ValueChanged, true);
            app.EqSlider64.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '128 Hz';
            app.EqSlider128 = uislider(app.GridLayout2);
            app.EqSlider128.Limits = [-1 1];
            app.EqSlider128.MajorTicks = [-1 0 1];
            app.EqSlider128.MajorTickLabels = {''};
            app.EqSlider128.ValueChangedFcn = createCallbackFcn(app, @EqSlider128ValueChanged, true);
            app.EqSlider128.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '256 Hz';
            app.EqSlider256 = uislider(app.GridLayout2);
            app.EqSlider256.Limits = [-1 1];
            app.EqSlider256.MajorTicks = [-1 0 1];
            app.EqSlider256.MajorTickLabels = {''};
            app.EqSlider256.ValueChangedFcn = createCallbackFcn(app, @EqSlider256ValueChanged, true);
            app.EqSlider256.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '512 Hz';
            app.EqSlider512 = uislider(app.GridLayout2);
            app.EqSlider512.Limits = [-1 1];
            app.EqSlider512.MajorTicks = [-1 0 1];
            app.EqSlider512.MajorTickLabels = {''};
            app.EqSlider512.ValueChangedFcn = createCallbackFcn(app, @EqSlider512ValueChanged, true);
            app.EqSlider512.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '1 KHz';
            app.EqSlider1k = uislider(app.GridLayout2);
            app.EqSlider1k.Limits = [-1 1];
            app.EqSlider1k.MajorTicks = [-1 0 1];
            app.EqSlider1k.MajorTickLabels = {''};
            app.EqSlider1k.ValueChangedFcn = createCallbackFcn(app, @EqSlider1kValueChanged, true);
            app.EqSlider1k.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '2 KHz';
            app.EqSlider2k = uislider(app.GridLayout2);
            app.EqSlider2k.Limits = [-1 1];
            app.EqSlider2k.MajorTicks = [-1 0 1];
            app.EqSlider2k.MajorTickLabels = {''};
            app.EqSlider2k.ValueChangedFcn = createCallbackFcn(app, @EqSlider2kValueChanged, true);
            app.EqSlider2k.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '4 KHz';
            app.EqSlider4k = uislider(app.GridLayout2);
            app.EqSlider4k.Limits = [-1 1];
            app.EqSlider4k.MajorTicks = [-1 0 1];
            app.EqSlider4k.MajorTickLabels = {''};
            app.EqSlider4k.ValueChangedFcn = createCallbackFcn(app, @EqSlider4kValueChanged, true);
            app.EqSlider4k.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = '8 KHz';
            app.EqSlider8k = uislider(app.GridLayout2);
            app.EqSlider8k.Limits = [-1 1];
            app.EqSlider8k.MajorTicks = [-1 0 1];
            app.EqSlider8k.MajorTickLabels = {''};
            app.EqSlider8k.ValueChangedFcn = createCallbackFcn(app, @EqSlider8kValueChanged, true);
            app.EqSlider8k.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_10 = uilabel(app.GridLayout2);
            app.Label_10.Text = '16 KHz';
            app.EqSlider16k = uislider(app.GridLayout2);
            app.EqSlider16k.Limits = [-1 1];
            app.EqSlider16k.MajorTicks = [-1 0 1];
            app.EqSlider16k.MajorTickLabels = {''};
            app.EqSlider16k.ValueChangedFcn = createCallbackFcn(app, @EqSlider16kValueChanged, true);
            app.EqSlider16k.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Synthesiser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end